classdef GA_optimizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GAOptimzerApp                  matlab.ui.Figure
        infoGAOpt                      matlab.ui.control.Label
        logsTextArea                   matlab.ui.control.TextArea
        GAlogsLabel                    matlab.ui.control.Label
        returnResultsToarPLSGUIButton  matlab.ui.control.Button
        GAoptimizationPanel            matlab.ui.container.Panel
        startGAoptimizationButton      matlab.ui.control.Button
        ParametersPanel                matlab.ui.container.Panel
        includeEndsOptimizationCheckBox  matlab.ui.control.CheckBox
        populationSizeSpinner          matlab.ui.control.Spinner
        crossingoverRateSpinner        matlab.ui.control.Spinner
        mutationRateSpinner            matlab.ui.control.Spinner
        MutationrateLabel              matlab.ui.control.Label
        maxiterationsLabel             matlab.ui.control.Label
        CrossingoverLabel              matlab.ui.control.Label
        maxIterationsSpinner           matlab.ui.control.Spinner
        populationsizeLabel            matlab.ui.control.Label
        CurvesandpeaksselectionforoptimizationPanel  matlab.ui.container.Panel
        addSelectedPointsButton        matlab.ui.control.Button
        brushPointsButton              matlab.ui.control.Button
        curveToOptimizationSpinner     matlab.ui.control.Spinner
        curveLabel                     matlab.ui.control.Label
        useOnlyOneCurveCheckBox        matlab.ui.control.CheckBox
        plot1                          matlab.ui.control.UIAxes
        plot2                          matlab.ui.control.UIAxes
        plot3                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % arPLS_gui app
        arPLSApp 
        % signals
        signals
        xLabels
        signalsNb
        signalsLen
        includePotentials
        reversPeaks
        % selected curves
        peakSelectedPoints = 0
        backgroundSelectedPoints = 0
        useOnlyOneCurveIsChecked
        curveToOpt = 0
        curvesSelectedToOpt = 0
%         curvesSelectedToOptNorm = 0
        curvesNbSelectedToOpt = 0
        % GA
        populationSize
        crossingOverProb
        mutationProb
        gaMaxIter
        includeEndsOptimization
        paramsCodedInBit = 16
        generationsSoFar
        currScoresMean
        currBestScore
        % arPLS
        bestLambda
        bestRatio
        bestIncludeEndsNb
        bkg
        arPLSMaxIter
        includeEndsNb
        threshold
    end
    
    methods (Access = private)
        
        % initial plot Signals
        function initialPlotSignals(app)
            if get(app.includePotentials,'Value')
                plot(app.plot1,app.xLabels,app.curvesSelectedToOpt,'-b');
            else
                plot(app.plot1,1:app.signalsLen,app.curvesSelectedToOpt,'-b');
            end
            plot(app.plot2,NaN);
            plot(app.plot3,NaN);
        end

        function bkgPlot(app)
            if get(app.includePotentials,'Value')
                plot(app.plot1,app.xLabels,app.curvesSelectedToOpt,'-b',app.xLabels,app.bkg,'-r');
            else
                plot(app.plot1,1:app.signalsLen,app.curvesSelectedToOpt,'-b',1:app.signalsLen,app.bkg,'-r');
            end
        end

        % plot scores
        function currentScoresPlot(app)
            plot(app.plot2,app.currScoresMean,'-rs');
            plot(app.plot3,app.currBestScore,'-rs');
        end

        % clear plot scores
        function clearScoresPlots(app)
            plot(app.plot2,NaN);
            plot(app.plot3,NaN);
        end


        % generation of initial population
        function population = populationGeneration(app)
            if app.includeEndsOptimization
                chromosomeLength = app.paramsCodedInBit*3;
            else
                chromosomeLength = app.paramsCodedInBit*2;
            end
            population=zeros(app.populationSize,chromosomeLength);
            r=zeros(app.populationSize,1);
            for i=1:app.populationSize
                r(i,:) = randi([0,chromosomeLength],1,1);
                if r(i,:) == 0
                   population(i,:) = zeros(1,chromosomeLength);
                elseif r(i,:) == chromosomeLength
                   population(i,:) = ones(1,chromosomeLength); 
                else
                   points=randperm(chromosomeLength);
                   for j=1:r(i,:)
                       population(i,points(j))=1;
                   end
                end
            end
        end


        % arPLS
        function [bkg,weights] = arPLS(app,lambda,ratio)

            % initializing values
            y = app.curvesSelectedToOpt;
%             y = app.curvesSelectedToOptNorm;

            % allocating memory
            bkg = zeros(app.signalsLen,app.curvesNbSelectedToOpt);
            weights = zeros(app.signalsLen,app.curvesNbSelectedToOpt);

            for i = 1:app.curvesNbSelectedToOpt
                N = app.signalsLen;
                D = diff(speye(N),2);
                H = lambda*D'*D;
                w = ones(N,1);
                cnt = 0;
                while true
                    W = spdiags(w,0,N,N);
                    %Cholesky decomposition
                    C = chol(W + H);
                    z = C \ (C'\(w.*y(:,i)));
                    d = y(:,i)-z;
                    %make d-, and get w^t with m and s
                    dn = d(d<0);
                    m = mean(dn);
                    s = std(dn);
                    wt = 1./ (1+ exp(2*(d-(2*s-m))/s));
                    %check exit condition and backup
                    if norm(w-wt)/norm(w) < ratio || cnt >= app.arPLSMaxIter.Value
                        [z,w] = includeEndsValueFunc(app,y(:,i),z,wt,N,H);
                        [z,w] = refineWeightsFunc(app,y(:,i),z,w,N,H);
                        break
                    end
                    w = wt;
                    cnt = cnt + 1;
                end
                
            bkg(:,i) = z;
            weights(:,i) = w;
            end
        end


        % Set w=1 for ends
        function [z,w] = includeEndsValueFunc(app,yt,zt,wt,N,H)
            if get(app.includeEndsNb,'Value')
                wt(1:app.includeEndsNb.Value) = 1;
                wt(end-app.includeEndsNb.Value:end) = 1;
                w = wt;
                W = spdiags(w,0,N,N);
                %Cholesky decomposition
                C = chol(W + H);
                z = C \ (C'\(w.*yt));
            else
                z = zt;
                w = wt;
            end
        end

        
        % refine Weights
        function [z,w] = refineWeightsFunc(app,yt,zt,wt,N,H)
            if get(app.threshold,'Value')
                wt(wt>app.threshold.Value) = 1;
                wt(wt<app.threshold.Value) = 0;
                w = wt;
                W = spdiags(w,0,N,N);
                %Cholesky decomposition
                C = chol(W + H);
                z = C \ (C'\(w.*yt));
            else
                z = zt;
                w = wt;
            end
        end


        % decoding chromosome to lambda value
        function [lambda] = chromosomToLambda(app,binArray)

            % initializing values
            minLambda = app.arPLSApp.lambdaText.Limits(1);
            maxLambda = app.arPLSApp.lambdaText.Limits(2);
            
            log10MinLambda = log10(minLambda);
            log10MaxLambda = log10(maxLambda);
            
            [decNumber,maxDecimal] = binArray2Dec(app,binArray);
            
            r = decNumber/maxDecimal;
            dr = (log10MaxLambda - log10MinLambda) * r;
            lambda = round(10 ^ (log10MinLambda + dr));
            
            if lambda > maxLambda
                lambda = maxLambda;
            elseif lambda < minLambda
                lambda = minLambda;
            end
        end


        % decoding chromosome to ratio value
        function [ratio] = chromosomToRatio(app,binArray)

            % initializing values
            minRatio = app.arPLSApp.ratioText.Limits(1);
            maxRatio = app.arPLSApp.ratioText.Limits(2);
            
            log10MinRatio = log10(minRatio);
            log10MaxRatio = log10(maxRatio);
            
            [decNumber,maxDecimal] = binArray2Dec(app,binArray);
            
            r = decNumber/maxDecimal;
            dr = (abs(log10MinRatio) - abs(log10MaxRatio)) * r;
            ratio = 10 ^ -(abs(log10MaxRatio) + dr);
            
            if ratio < minRatio
                ratio = minRatio;
            elseif ratio > maxRatio
                ratio = maxRatio;
            end
        end


        % decoding chromosome to endsIncluded value
        function [endsIncluded] = chromosomToEndsIncluded(app,binArray)

            minEndsIncluded = app.arPLSApp.includeEndsValue.Limits(1);
            maxEndsIncluded = app.arPLSApp.includeEndsValue.Limits(2);
            
            [decNumber,maxDecimal] = binArray2Dec(app,binArray);
            
            r = decNumber/maxDecimal;
            dr = (maxEndsIncluded - minEndsIncluded) * r;
            endsIncluded = round(dr);
            
            if endsIncluded > maxEndsIncluded
                endsIncluded = maxEndsIncluded;
            elseif endsIncluded < minEndsIncluded
                endsIncluded = minEndsIncluded;
            end
        end


        % binary array to decimal number
        function [decimal,maxDecimal] = binArray2Dec(app,binArray)

            % initializing values
            len = app.paramsCodedInBit;
            flippedBinArray = flip(binArray);

            % allocating memory
            decodedArray = zeros(1,len);
            maxDecimalArray = zeros(1,app.paramsCodedInBit);
            
            for i = 1:len
                decodedArray(:,i) = flippedBinArray(:,i) * 2^(i-1);
                maxDecimalArray(:,i) = 2^(i-1);
            end
            
            decimal = sum(decodedArray);
            maxDecimal = sum(maxDecimalArray)+1;
        end


        % fitness Function
        function [scores] = fitnessFunction(app,population)

            app.logsTextArea.Value = [app.logsTextArea.Value(:)', {'Fitness function calculation ...,'}];
            drawnow

            % initializing some values
            bkgPoints = app.backgroundSelectedPoints(:,end);
            peakPoints = app.peakSelectedPoints(:,end);
            popSize = app.populationSize;
            peakPointsNb = nnz(peakPoints);
            bkgPointsNb = app.signalsLen - peakPointsNb;

            % allocating memory
            lambdas = zeros(popSize,1);
            ratios = zeros(popSize,1);
            includeEndsNumbers = zeros(popSize,1);
            ffbp = zeros(popSize,1);
            ffdbsb = zeros(popSize,1);
            ffdbsp = zeros(popSize,1);
            scores = zeros(popSize,1);
            
            for i=1:popSize

                % decoding lambda and ratio parameters
                % from population of genetic algorithm
                lambdas(i,:) = chromosomToLambda(app,population(i,1:app.paramsCodedInBit));
                ratios(i,:) = chromosomToRatio(app,population(i,app.paramsCodedInBit+1:app.paramsCodedInBit*2));
                
                % if includedEnds optimization
                if app.includeEndsOptimization
                    includeEndsNumbers(i,:) = chromosomToEndsIncluded(app,population(i,(app.paramsCodedInBit*2+1):app.paramsCodedInBit*3));
                    app.includeEndsNb.Value = includeEndsNumbers(i,:);
                end

                % arPLS for each lambda and ratio parameter
                [background,weights] = arPLS(app,lambdas(i,:),ratios(i,:));
                
                % fittnes function
                % background points
                d1 = weights - bkgPoints;
                s1 = sum(abs(d1));
                ffbp(i,:) = sum(s1);
                
                % difference between arPLS background and signals in background interval
                d2 = abs(app.curvesSelectedToOpt - background).*bkgPoints;
                s2 = sum(d2);
                ffdbsb(i,:) = sum(s2);

                % difference between arPLS background and signals in peak interval
                d3 = abs(app.curvesSelectedToOpt - background).*peakPoints;
                s3 = sum(d3);
                ffdbsp(i,:) = sum(s3);
            
                % calculating scores
                scores(i,:) = (ffbp(i,:) + (ffdbsb(i,:)/bkgPointsNb) - (ffdbsp(i,:)/peakPointsNb)) / app.curvesNbSelectedToOpt;
            end
        end


        function expectation = fitScalingRank(app,scores)
            % FITSCALINGRANK Rank based fitness scaling.
            %   EXPECTATION = FITSCALINGRANK(SCORES,NPARENTS) calculates the
            %   EXPECTATION using the SCORES and number of parents NPARENTS.
            %   This relationship can be linear or nonlinear.
            %
            %   Example:
            %   Create an options structure using FITSCALINGRANK as 
            %   the fitness scaling function
            %     options = gaoptimset('FitnessScalingFcn',@fitscalingrank);
            
            %   Copyright 2003-2004 The MathWorks, Inc.
            %   $Revision: 1.7.4.1 $  $Date: 2004/08/20 19:48:14 $
            
            [~,i] = sort(scores); 
            expectation = zeros(size(scores));
           
            expectation(i) = 1 ./ ((1:length(scores))  .^ 0.5);
            expectation = app.populationSize * expectation ./ sum(expectation);
        end


        function parents = selectionStochUnif(app,expectation)
            %SELECTIONSTOCHUNIF Choose parents using stochastic universal sampling (SUS).
            %   PARENTS = SELECTIONSTOCHUNIF(EXPECTATION,NPARENTS,OPTIONS) chooses the 
            %   PARENTS using roulette wheel and uniform sampling, based on EXPECTATION 
            %   and number of parents NPARENTS. 
            %
            
            %   Given a roulette wheel with a slot for each expectation whose size is 
            %   equal to the expectation. We then step through the wheel in equal size 
            %   steps, so as to cover the entire wheel in nParents steps. At each step, 
            %   we create a parent from the slot we have landed in. This mechanism is 
            %   fast and accurate.
            
            %   Copyright 2003-2004 The MathWorks, Inc.
            %   $Revision: 1.8.4.1 $  $Date: 2004/08/20 19:50:07 $
            
            wheel = cumsum(expectation) / app.populationSize;
            
            parents = zeros(1,app.populationSize);
            
            % we will step through the wheel in even steps.
            stepSize = 1/app.populationSize;
            
            % we will start at a random position less that one full step
            position = rand * stepSize;
            
            % a speed optimization. Position is monotonically rising.
            lowest = 1; 
            
            for i = 1:app.populationSize % for each parent needed,
                for j = lowest:length(wheel) % find the wheel position
                    if(position < wheel(j)) % that this step falls in.
                        parents(i) = j;
                        lowest = j;
                        break;
                    end
                end
                position = position + stepSize; % take the next step.
            end
        end


        function xoverKids  = crossoverSinglePoint(app,parents,thisPopulation)
            % CROSSOVERSINGLEPOINT Single point crossover.
            %   XOVERKIDS = CROSSOVERSINGLEPPOINT(PARENTS,OPTIONS,GENOMELENGTH, ...
            %   FITNESSFCN,SCORES,THISPOPULATION) creates the crossover children XOVERKIDS
            %   of the given population THISPOPULATION using the available parents
            %   PARENTS. A single crossover point for each child is chosen at random. 
            %   The child has the genes of the first parent up to this point and the genes 
            %   of the other parent after this point.
            
            %   Copyright 2003-2004 The MathWorks, Inc.
            %   $Revision: 1.9.4.1 $  $Date: 2004/08/20 19:48:09 $
            
            % Chromosome length
            if app.includeEndsOptimization
                GenomeLength = app.paramsCodedInBit*3;
            else
                GenomeLength = app.paramsCodedInBit*2;
            end
            
            % How many children to produce?
            nKids = length(parents);
            
            % Allocate space for the kids
            xoverKids = zeros(nKids,GenomeLength);
            
            % Parents selection
            parentSelection(1,:) = 1:nKids;
            parentSelection(2,:) = [2:nKids, 1];
            
            for i=1:nKids
                % each chromosome has crossingoverRate chance of being crossingovered
                if rand() < app.crossingOverProb
                    % Crossingover
            
                    % get parents
                    parent1 = thisPopulation(parents(parentSelection(1,i)),:);
                    parent2 = thisPopulation(parents(parentSelection(2,i)),:);
            
                    % cut point is AFTER this index.
                    xOverPoint = ceil(rand * (length(parent1) - 1));
                    
                    % make children
                    xoverKids(i,:) = [parent1(1:xOverPoint),parent2(xOverPoint+1:GenomeLength)]; 
                else
                    % NO crossingover
            
                    % copy parent
                    xoverKids(i,:) = thisPopulation(parents(parentSelection(1,i)),:);
                end
            
            end
        end


        function mutationChildren = mutationUniform(app,parents,thisPopulation)
            %MUTATIONUNIFORM Uniform multi-point mutation.
            %   MUTATIONCHILDREN = MUTATIONUNIFORM(PARENTS,GENOMELENGTH,...
            %                      THISPOPULATION, MUTATIONRATE) Creates the mutated children using
            %   uniform mutations at multiple points. Mutated genes are uniformly 
            %   distributed over the range of the gene. The new value is NOT a function
            %   of the parents value for the gene.
            %
            %   Copyright 2003-2015 The MathWorks, Inc.

            % Chromosome length
            if app.includeEndsOptimization
                GenomeLength = app.paramsCodedInBit*3;
            else
                GenomeLength = app.paramsCodedInBit*2;
            end

            % How many children to produce?
            nKids = length(parents);

            % Allocation of memory
            mutationChildren = zeros(nKids,GenomeLength);
            
            for i=1:nKids
                % each child
                child = thisPopulation(i,:);
                
                % each element of the genome has mutationRate chance of being mutated.
                mutationPoints = find(rand(1,length(child)) < app.mutationProb);
            
                % changing value of the gene to opposite
                mpl = length(mutationPoints);
                for j=1:mpl
                    if child(mutationPoints(j)) == 1
                        child(mutationPoints(j)) = 0;
                    else
                        child(mutationPoints(j)) = 1;
                    end
                end
            
                mutationChildren(i,:) = child;
            end
        end

        % best Lambda and Ratio from last population 
        function bestChromosomeResults(app,bestChromosome)
            % Decoding best chromosome from last population to lambda and ratio
            % parameters and calculating arPLS background
            
            % decoding lambda and ratio parameters from best chromosome
            app.bestLambda = chromosomToLambda(app,bestChromosome(1,1:app.paramsCodedInBit));
            app.bestRatio = chromosomToRatio(app,bestChromosome(1,app.paramsCodedInBit+1:app.paramsCodedInBit*2));
            if app.includeEndsOptimization
                app.includeEndsNb.Value = chromosomToEndsIncluded(app,bestChromosome(1,(app.paramsCodedInBit*2+1):app.paramsCodedInBit*3));
            end
            app.bestIncludeEndsNb = app.includeEndsNb.Value;
            
            % arPLS for each lambda and ratio parameter
            [app.bkg,~] = arPLS(app,app.bestLambda,app.bestRatio);            
        end


        % Genetic Algorithm
        function geneticAlgorithm(app)

            app.logsTextArea.Value = '';
            app.logsTextArea.Value = {'Initializing GA Optimization ...'};
            app.infoGAOpt.Text = "Optimizing arPLS parameters";

            app.generationsSoFar = [];
            app.currBestScore = [];
            app.currScoresMean = [];

            % clear score plots
            clearScoresPlots(app);

            if length(app.peakSelectedPoints(:,1)) == 1
                app.logsTextArea.Value = {'GA optimization terminated. Select peaks position first'};
                return
            end

            % Generation of the population
            population = populationGeneration(app);
            app.logsTextArea.Value = [app.logsTextArea.Value(:)', {'Population generated,'}];
            app.logsTextArea.Value = [app.logsTextArea.Value(:)', {'------------------------------------------'}];
            
            % Main loop
            for currGen = 1:app.gaMaxIter

                app.generationsSoFar(currGen,:) = currGen;
                
                % current generation log message
                currGenMessage = sprintf('Current generation: %d/%d,',currGen,app.gaMaxIter);
                app.logsTextArea.Value = [app.logsTextArea.Value(:)', {currGenMessage}];
%                 if ~isMATLABReleaseOlderThan("R2020b")
%                     scroll(app.logsTextArea, 'bottom');
%                 end

                % Fitness function
                scores = fitnessFunction(app,population);

                % Fitness scaling
                expectation = fitScalingRank(app,scores);
                [~,bestIndex] = max(expectation);
                currBestChromosome(currGen,:) = population(bestIndex,:);
                app.currScoresMean(:,currGen) = mean(scores);
                app.currBestScore(currGen,:) = scores(bestIndex,:);
                
                % Upgrade scores plot
                currentScoresPlot(app);

                if currGen < app.gaMaxIter

                    % Selection new population
                    parents = selectionStochUnif(app,expectation);
                    
                    % Crossingover (with new population creation)
                    population = crossoverSinglePoint(app,parents,population);
                    app.logsTextArea.Value = [app.logsTextArea.Value(:)', {'Crossingover,'}];

                    % Mutation
                    population = mutationUniform(app,parents,population);
                    app.logsTextArea.Value = [app.logsTextArea.Value(:)', {'Mutation,'}];

                end

            end

            % finishing calculations message
            app.logsTextArea.Value = [app.logsTextArea.Value(:)', {'Finishing calculations,'}];
            app.infoGAOpt.Text = "Optimization finished. Ready to return parameters";

            % best Lambda and Ratio from last population 
            bestChromosome = currBestChromosome(end,:);
            bestChromosomeResults(app,bestChromosome);
%             bkgPlot(app);
            % best Lambda and Ratio log message
            app.logsTextArea.Value = [app.logsTextArea.Value(:)', {'------------------------------------------'}];
            bestLambdaMessage = sprintf('Best Lambda: %d,',app.bestLambda);
            app.logsTextArea.Value = [app.logsTextArea.Value(:)', {bestLambdaMessage}];
            bestRatioMessage = sprintf('Best Ratio: %d,',app.bestRatio);
            app.logsTextArea.Value = [app.logsTextArea.Value(:)', {bestRatioMessage}];
            if app.includeEndsOptimization
                bestIncludeEndsMessage = sprintf('Best Include end points: %d,',app.bestIncludeEndsNb);
                app.logsTextArea.Value = [app.logsTextArea.Value(:)', {bestIncludeEndsMessage}];
            end
%             if ~isMATLABReleaseOlderThan("R2020b")
%                 scroll(app.logsTextArea, 'bottom');
%             end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, signals)
            % Store main app object
            app.arPLSApp = caller;
        
            % Process input data
            % includePotentials
            app.includePotentials = app.arPLSApp.includePotentials;
            % signals
            if get(app.includePotentials,'Value')
                app.signals = signals(:,2:end);
                app.curvesSelectedToOpt = signals(:,2:end);
                app.xLabels = signals(:,1);
            else
                app.signals = signals;
                app.curvesSelectedToOpt = signals;
            end
            % signalsNb
            [~,app.signalsNb] = size(app.signals);
            % curvesNbSelectedToOpt
            [~,app.curvesNbSelectedToOpt] = size(app.curvesSelectedToOpt);
            % signalsLen
            [app.signalsLen,~] = size(app.signals);
            % reversPeaks
            app.reversPeaks = app.arPLSApp.reversPeaks;
            % maxIter
            app.arPLSMaxIter = app.arPLSApp.maxIterations;
            % includeEndsNb
            app.includeEndsNb = app.arPLSApp.includeEndsValue;
            % threshold
            app.threshold = app.arPLSApp.refineWeights;

            % Initial values
            % addSelectedPointsButton
            app.addSelectedPointsButton.Enable = "off";
            % curveToOptimizationSpinner
            app.curveToOptimizationSpinner.Enable = "off";
            % curve to optimization Spinner Initial Limits, Value and
            % CheckBox Enable
            if app.signalsNb > 1
                set(app.curveToOptimizationSpinner, 'Limits', [1 app.signalsNb]);
                app.useOnlyOneCurveCheckBox.Enable = "on";
                app.infoGAOpt.Text = "Select curves and brush the peak points";
            else
                set(app.curveToOptimizationSpinner, 'Limits', [-Inf Inf]);
                app.useOnlyOneCurveCheckBox.Enable = "off";
                app.infoGAOpt.Text = "Brush the peak points";
            end
            set(app.curveToOptimizationSpinner, 'Value', app.signalsNb);
            % ga parameters
            app.populationSize = app.populationSizeSpinner.Value;
            app.crossingOverProb = app.crossingoverRateSpinner.Value;
            app.mutationProb = app.mutationRateSpinner.Value;
            app.gaMaxIter = app.maxIterationsSpinner.Value;
            app.includeEndsOptimization = app.includeEndsOptimizationCheckBox.Value;

            % Draw signals in plot1
            initialPlotSignals(app);
        end

        % Close request function: GAOptimzerApp
        function GAOptimzerAppCloseRequest(app, event)
            % Enable the GA Optimizer button in main app
            set(app.arPLSApp.GAOptimizerButton, "Enable", "on");
            % Delete GA Optimizer app
            delete(app);
        end

        % Button pushed function: addSelectedPointsButton
        function addSelectedPointsButtonPushed(app, event)
            sp = false(app.signalsLen,app.curvesNbSelectedToOpt);
            for i = 1:app.curvesNbSelectedToOpt
                sp(:,i) = logical(app.plot1.Children(i).BrushData);
            end
            app.peakSelectedPoints = double(sp);
            app.backgroundSelectedPoints = double(~(sp));
            brush(app.plot1, 'off');
            app.addSelectedPointsButton.Enable = "off";
            app.startGAoptimizationButton.Enable = "on";
            app.infoGAOpt.Text = "Ready for optimization";
        end

        % Button pushed function: brushPointsButton
        function brushPointsButtonPushed(app, event)
            brush(app.plot1, 'on');
            app.addSelectedPointsButton.Enable = "on";
        end

        % Value changed function: useOnlyOneCurveCheckBox
        function useOnlyOneCurveCheckBoxValueChanged(app, event)
            app.useOnlyOneCurveIsChecked = app.useOnlyOneCurveCheckBox.Value;
            if app.useOnlyOneCurveIsChecked
                app.curveToOptimizationSpinner.Enable ="on";
                app.curveToOpt = app.curveToOptimizationSpinner.Value;
                app.curvesSelectedToOpt = app.signals(:,app.curveToOpt);
                app.curvesNbSelectedToOpt = 1;
            else
                app.curveToOptimizationSpinner.Enable ="off";
                app.curvesSelectedToOpt = app.signals;
                app.curvesNbSelectedToOpt = app.signalsNb;
            end
            initialPlotSignals(app);
        end

        % Value changed function: curveToOptimizationSpinner
        function curveToOptimizationSpinnerValueChanged(app, event)
            app.curveToOpt = app.curveToOptimizationSpinner.Value;
            if app.useOnlyOneCurveIsChecked
                app.curvesSelectedToOpt = app.signals(:,app.curveToOpt);
                app.curvesNbSelectedToOpt = 1;
                initialPlotSignals(app);
            end
        end

        % Button pushed function: startGAoptimizationButton
        function startGAoptimizationButtonPushed(app, event)
            % set enable off
            app.returnResultsToarPLSGUIButton.Enable = "off";
            app.brushPointsButton.Enable = "off";
            app.curveToOptimizationSpinner.Enable = "off";
            app.useOnlyOneCurveCheckBox.Enable = "off";
            app.populationSizeSpinner.Enable = "off";
            app.crossingoverRateSpinner.Enable = "off";
            app.mutationRateSpinner.Enable = "off";
            app.maxIterationsSpinner.Enable = "off";
            app.includeEndsOptimizationCheckBox.Enable = "off";
            app.startGAoptimizationButton.Enable = "off";
            % run optimization
            geneticAlgorithm(app);
            % set enable on
            app.returnResultsToarPLSGUIButton.Enable = "on";
            app.brushPointsButton.Enable = "on";           
            if app.signalsNb > 1
                app.useOnlyOneCurveCheckBox.Enable = "on";
                app.curveToOptimizationSpinner.Enable = "on";
            else
                app.useOnlyOneCurveCheckBox.Enable = "off";
                app.curveToOptimizationSpinner.Enable = "off";
            end
            app.populationSizeSpinner.Enable = "on";
            app.crossingoverRateSpinner.Enable = "on";
            app.mutationRateSpinner.Enable = "on";
            app.maxIterationsSpinner.Enable = "on";
            app.includeEndsOptimizationCheckBox.Enable = "on";
            app.startGAoptimizationButton.Enable = "on";
        end

        % Value changed function: populationSizeSpinner
        function populationSizeSpinnerValueChanged(app, event)
            app.populationSize = app.populationSizeSpinner.Value;
        end

        % Value changed function: crossingoverRateSpinner
        function crossingoverRateSpinnerValueChanged(app, event)
            app.crossingOverProb = app.crossingoverRateSpinner.Value;
        end

        % Value changed function: maxIterationsSpinner
        function maxIterationsSpinnerValueChanged(app, event)
            app.gaMaxIter = app.maxIterationsSpinner.Value;
        end

        % Value changed function: mutationRateSpinner
        function mutationRateSpinnerValueChanged(app, event)
            app.mutationProb = app.mutationRateSpinner.Value;
        end

        % Button down function: GAOptimzerApp
        function returnResultsToarPLSGui(app, event)
            % Call main app's public function
            fitBackgroundAfterGAOptimization(app.arPLSApp,app.bestLambda,app.bestRatio,app.bestIncludeEndsNb);
        end

        % Button pushed function: returnResultsToarPLSGUIButton
        function returnResultsToarPLSGUIButtonPushed(app, event)
            % Call main app's public function
            fitBackgroundAfterGAOptimization(app.arPLSApp,app.bestLambda,app.bestRatio,app.bestIncludeEndsNb);
            app.infoGAOpt.Text = "Parameters returned to arPLS main window";
        end

        % Value changed function: includeEndsOptimizationCheckBox
        function includeEndsOptimizationCheckBoxValueChanged(app, event)
            app.includeEndsOptimization = app.includeEndsOptimizationCheckBox.Value;
            if app.includeEndsOptimization == 0
                app.includeEndsNb.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GAOptimzerApp = uifigure('Visible', 'off');
            app.GAOptimzerApp.Name = 'GA Optimzer';
            app.GAOptimzerApp.CloseRequestFcn = createCallbackFcn(app, @GAOptimzerAppCloseRequest, true);
            app.GAOptimzerApp.ButtonDownFcn = createCallbackFcn(app, @returnResultsToarPLSGui, true);
            app.plot3 = uiaxes(app.GAOptimzerApp);
            title(app.plot3, 'GA optimizer best score')
            xlabel(app.plot3, 'generation')
            ylabel(app.plot3, 'score')
            app.plot2 = uiaxes(app.GAOptimzerApp);
            title(app.plot2, 'GA optimizer mean score')
            xlabel(app.plot2, 'generation')
            ylabel(app.plot2, 'score')
            app.plot1 = uiaxes(app.GAOptimzerApp);
            title(app.plot1, 'Curves and peaks selected for optimization')
            xlabel(app.plot1, 'point number')
            ylabel(app.plot1, 'a.u.')
            app.CurvesandpeaksselectionforoptimizationPanel = uipanel(app.GAOptimzerApp);
            app.CurvesandpeaksselectionforoptimizationPanel.Title = 'Curves and peaks selection for optimization';
            app.useOnlyOneCurveCheckBox = uicheckbox(app.CurvesandpeaksselectionforoptimizationPanel);
            app.useOnlyOneCurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @useOnlyOneCurveCheckBoxValueChanged, true);
            app.useOnlyOneCurveCheckBox.Text = 'use only one curve:';
            app.curveLabel = uilabel(app.CurvesandpeaksselectionforoptimizationPanel);
            app.curveLabel.Text = 'curve';
            app.curveToOptimizationSpinner = uispinner(app.CurvesandpeaksselectionforoptimizationPanel);
            app.curveToOptimizationSpinner.ValueChangedFcn = createCallbackFcn(app, @curveToOptimizationSpinnerValueChanged, true);
            app.curveToOptimizationSpinner.Value = 1;
            app.brushPointsButton = uibutton(app.CurvesandpeaksselectionforoptimizationPanel, 'push');
            app.brushPointsButton.ButtonPushedFcn = createCallbackFcn(app, @brushPointsButtonPushed, true);
            app.brushPointsButton.Text = 'Brush peak points';
            app.addSelectedPointsButton = uibutton(app.CurvesandpeaksselectionforoptimizationPanel, 'push');
            app.addSelectedPointsButton.ButtonPushedFcn = createCallbackFcn(app, @addSelectedPointsButtonPushed, true);
            app.addSelectedPointsButton.Text = 'Add selection';
            app.GAoptimizationPanel = uipanel(app.GAOptimzerApp);
            app.GAoptimizationPanel.Title = 'GA optimization';
            app.ParametersPanel = uipanel(app.GAoptimizationPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.populationsizeLabel = uilabel(app.ParametersPanel);
            app.populationsizeLabel.Text = 'population size';
            app.maxIterationsSpinner = uispinner(app.ParametersPanel);
            app.maxIterationsSpinner.Limits = [5 100];
            app.maxIterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @maxIterationsSpinnerValueChanged, true);
            app.maxIterationsSpinner.Value = 20;
            app.CrossingoverLabel = uilabel(app.ParametersPanel);
            app.CrossingoverLabel.Text = 'crossover rate';
            app.maxiterationsLabel = uilabel(app.ParametersPanel);
            app.maxiterationsLabel.Text = 'max iterations';
            app.MutationrateLabel = uilabel(app.ParametersPanel);
            app.MutationrateLabel.Text = 'mutation rate';
            app.mutationRateSpinner = uispinner(app.ParametersPanel);
            app.mutationRateSpinner.Step = 0.01;
            app.mutationRateSpinner.Limits = [0.001 0.2];
            app.mutationRateSpinner.ValueChangedFcn = createCallbackFcn(app, @mutationRateSpinnerValueChanged, true);
            app.mutationRateSpinner.Value = 0.01;
            app.crossingoverRateSpinner = uispinner(app.ParametersPanel);
            app.crossingoverRateSpinner.Step = 0.01;
            app.crossingoverRateSpinner.Limits = [0.5 0.95];
            app.crossingoverRateSpinner.ValueChangedFcn = createCallbackFcn(app, @crossingoverRateSpinnerValueChanged, true);
            app.crossingoverRateSpinner.Value = 0.8;
            app.populationSizeSpinner = uispinner(app.ParametersPanel);
            app.populationSizeSpinner.Limits = [16 1024];
            app.populationSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @populationSizeSpinnerValueChanged, true);
            app.populationSizeSpinner.Value = 64;
            app.includeEndsOptimizationCheckBox = uicheckbox(app.ParametersPanel);
            app.includeEndsOptimizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @includeEndsOptimizationCheckBoxValueChanged, true);
            app.includeEndsOptimizationCheckBox.Text = 'include wbe in optimization';
            app.includeEndsOptimizationCheckBox.Value = true;
            app.startGAoptimizationButton = uibutton(app.GAoptimizationPanel, 'push');
            app.startGAoptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @startGAoptimizationButtonPushed, true);
            app.startGAoptimizationButton.Enable = 'off';
            app.startGAoptimizationButton.Text = 'Start optimization';
            app.returnResultsToarPLSGUIButton = uibutton(app.GAOptimzerApp, 'push');
            app.returnResultsToarPLSGUIButton.ButtonPushedFcn = createCallbackFcn(app, @returnResultsToarPLSGUIButtonPushed, true);
            app.returnResultsToarPLSGUIButton.Enable = 'off';
            app.returnResultsToarPLSGUIButton.Text = 'Return results to arPLS window';
            app.GAlogsLabel = uilabel(app.GAOptimzerApp);
            app.GAlogsLabel.Text = 'GA logs';
            app.logsTextArea = uitextarea(app.GAOptimzerApp);
            app.infoGAOpt = uilabel(app.GAOptimzerApp);
            app.infoGAOpt.Text = 'Select curves and brush the peak points';
            % Show the figure after all components are created
            app.GAOptimzerApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GA_optimizer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GAOptimzerApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GAOptimzerApp)
        end
    end
end