classdef arPLS_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        arPLSApp                    matlab.ui.Figure
        fileMenu                    matlab.ui.container.Menu
        loadFile                    matlab.ui.container.Menu
        exportFitToFile             matlab.ui.container.Menu
        exportFitToWorkspace        matlab.ui.container.Menu
        info                        matlab.ui.control.Label
        ExcludecurvesPanel          matlab.ui.container.Panel
        toggleAllCurves             matlab.ui.control.Button
        includeCurves               matlab.ui.control.Table
        ConstraincurvespointsPanel  matlab.ui.container.Panel
        Label_2                     matlab.ui.control.Label
        varEnd                      matlab.ui.control.Spinner
        varBeg                      matlab.ui.control.Spinner
        plotSignalsWithoutBkg       matlab.ui.control.Button
        fitBackground               matlab.ui.control.Button
        arPLSparametersPanel        matlab.ui.container.Panel
        GAOptimizerButton           matlab.ui.control.Button
        RatioPanel                  matlab.ui.container.Panel
        ratioText                   matlab.ui.control.NumericEditField
        ratioSlider                 matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        OtherPanel                  matlab.ui.container.Panel
        refineWeights               matlab.ui.control.Spinner
        refinewthresholdLabel       matlab.ui.control.Label
        includeEndsValue            matlab.ui.control.Spinner
        SetW1forendpointsLabel      matlab.ui.control.Label
        maxIterations               matlab.ui.control.Spinner
        maxnumberofiterationsLabel  matlab.ui.control.Label
        LambdaPanel                 matlab.ui.container.Panel
        lambdaText                  matlab.ui.control.NumericEditField
        lambdaSlider                matlab.ui.control.Slider
        SliderLabel                 matlab.ui.control.Label
        loadedsSgnalsPanel          matlab.ui.container.Panel
        reversPeaks                 matlab.ui.control.CheckBox
        includePotentials           matlab.ui.control.CheckBox
        listOfVariables             matlab.ui.control.DropDown
        SelectedvariabLabel         matlab.ui.control.Label
        FitarPLSbackgroundLabel     matlab.ui.control.Label
        ImportedsignalsLabel        matlab.ui.control.Label
        plot2                       matlab.ui.control.UIAxes
        plot1                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        loadedFileStruct
        background
        weights
        variableWithoutBkg
        plotSwitch
        lambdaValue
        ratioValue
        selectedVariable
        selectedVariableName
        curvesIncluded
        GAOptimizerApp % GA Optimizer app
    end
    
    
    methods (Access = private)
        
        function app = createListOfCurves(app)
            [~,n] = size(app.selectedVariable);
            if get(app.includePotentials,'Value') 
                n=n-1; 
            end
            includeList = {};
            included = 0;
            for i = 1:n
               includeList{i,1} = sprintf('curve_%d',i);
               includeList{i,2} = true;
               included(i,1) = true;
            end
            app.curvesIncluded = included;
            app.includeCurves.Data = includeList;
        end
        
        % plot Data
        function [] = plotData(app)
            varToPlot = app.selectedVariable;
            curvesIncl = app.curvesIncluded;
            beg = get(app.varBeg,'Value');
            endd = get(app.varEnd,'Value');
            if get(app.includePotentials,'Value') && sum(curvesIncl) >= 1
                curvesIncl = [0; curvesIncl];
                plot(app.plot1,varToPlot(beg:endd,1),varToPlot(beg:endd,curvesIncl>0),'-b');
                plot(app.plot2,NaN);
            elseif sum(curvesIncl) >= 1
                plot(app.plot1,varToPlot(beg:endd,curvesIncl>0),'-b');
                plot(app.plot2,NaN);
            else
                plot(app.plot1,NaN);
                plot(app.plot2,NaN);
            end 
        end
        
        function [] = includePotentialsFunc(app)
            plotData(app);
        end
        
        % reverse peaks
        function app = reversPeaksFunc(app)
            if get(app.includePotentials,'Value')
                app.selectedVariable(:,2:end) = app.selectedVariable(:,2:end) * -1;
            else
                app.selectedVariable = app.selectedVariable * -1;
            end
            plotData(app);
        end
        
        % arPLS
        function app = arPLS(app,y)
            set(app.fitBackground, 'Enable', 'off');
            set(app.fitBackground, 'Text', 'Calculating');
            
            lambda = app.lambdaValue;
            ratio = app.ratioValue;
            maxIter = get(app.maxIterations,'Value');
            
            [m2,n2] = size(y);
            
            for i = 1:n2
                
                app.info.Text = ['Calculating background for curve: ' num2str(i)];
                drawnow
                
                N = m2;
                D = diff(speye(N),2);
                H = lambda*D'*D;
                w = ones(N,1);
                cnt = 0;
                while true
                    W = spdiags(w,0,N,N);
                    %Cholesky decomposition
                    C = chol(W + H);
                    z = C \ (C'\(w.*y(:,i)));
                    d = y(:,i)-z;
                    %make d-, and get w^t with m and s
                    dn = d(d<0);
                    m = mean(dn);
                    s = std(dn);
                    wt = 1./ (1+ exp(2*(d-(2*s-m))/s));
                    %check exit condition and backup
                    if norm(w-wt)/norm(w) < ratio || cnt >= maxIter
                        [z,w] = includeEndsValueFunc(app,y(:,i),z,wt,N,H);
                        [z,w] = refineWeightsFunc(app,y(:,i),z,w,N,H);
                        break
                    end
                    w = wt;
                    cnt = cnt + 1;
                end
                
            Z(:,i) = z;
            Weights(:,i) = w;
            end
            app.background = Z;
            app.weights = Weights;
            set(app.fitBackground, 'Enable', 'on');
            set(app.fitBackground, 'Text', 'Fit background');
        end
        
        % Set w=1 for ends
        function [z,w] = includeEndsValueFunc(app,yt,zt,wt,N,H)
            if get(app.includeEndsValue,'Value')
                includeEndsNb = get(app.includeEndsValue,'Value');
                wt(1:includeEndsNb) = 1;
                wt(end-includeEndsNb:end) = 1;
                w = wt;
                W = spdiags(w,0,N,N);
                %Cholesky decomposition
                C = chol(W + H);
                z = C \ (C'\(w.*yt));
            else
                z = zt;
                w = wt;
            end
        end
        
        % refine Weights
        function [z,w] = refineWeightsFunc(app,yt,zt,wt,N,H)
            if get(app.refineWeights,'Value')
                threshold = get(app.refineWeights,'Value');
                wt(wt>threshold) = 1;
                wt(wt<threshold) = 0;
                w = wt;
                W = spdiags(w,0,N,N);
                %Cholesky decomposition
                C = chol(W + H);
                z = C \ (C'\(w.*yt));
            else
                z = zt;
                w = wt;
            end
        end
        
        % plot Data with fitted background
        function [] = plotDataAndBkg(app)
            varToPlot = app.selectedVariable;
            bkg = app.background;
            w = app.weights;
            curvesIncl = app.curvesIncluded;
            beg = get(app.varBeg,'Value');
            endd = get(app.varEnd,'Value');
            [m,~] = size(bkg);
            if get(app.includePotentials,'Value') && sum(curvesIncl) >= 1
                curvesIncl = [0; curvesIncl];
                plot(app.plot1,varToPlot(beg:endd,1),varToPlot(beg:endd,curvesIncl>0),'-b',varToPlot(beg:endd,1),bkg,'-r');
                plot(app.plot2,varToPlot(beg:endd,1),w,'-k');
                app.plot2.YLim = [-0.1 1.1];
            elseif sum(curvesIncl) >= 1
                plot(app.plot1,1:m,varToPlot(beg:endd,curvesIncl>0),'-b',1:m,bkg,'-r');
                plot(app.plot2,1:m,w,'-k');
                app.plot2.YLim = [-0.1 1.1];
            else
                plot(app.plot1,NaN);
                plot(app.plot2,1:m,w,'-k');
            end
        end
        
        % plot Data without background
        function [] = plotDataWithoutBkg(app)
            varToPlot = app.variableWithoutBkg;
            curvesIncl = app.curvesIncluded;
            if get(app.includePotentials,'Value') && sum(curvesIncl) >= 1
                plot(app.plot1,varToPlot(:,1),varToPlot(:,2:end),'-b');
            elseif sum(curvesIncl) >= 1
                plot(app.plot1,varToPlot,'-b');
            else
                plot(app.plot1,NaN);
            end
        end
        
        % clear app from data
        function [app] = clearAndDefaultApp(app)
            if length(app.selectedVariable) > 1
                set(app.exportFitToFile, 'Enable', 'off');
                set(app.exportFitToWorkspace, 'Enable', 'off');
                set(app.includePotentials, 'Enable', 'off');
                set(app.reversPeaks, 'Enable', 'off');
                set(app.fitBackground, 'Enable', 'off');
                set(app.GAOptimizerButton, 'Enable', 'off');
                set(app.varBeg, 'Enable', 'off');
                set(app.varEnd, 'Enable', 'off');
                set(app.includeEndsValue, 'Enable', 'off');
                set(app.refineWeights, 'Enable', 'off');
                set(app.includePotentials,'Enable', 'off');
                set(app.plotSignalsWithoutBkg, 'Enable', 'off');
                set(app.includeCurves, 'Enable', 'off');
                set(app.toggleAllCurves, 'Enable', 'off');
                set(app.includePotentials, 'Value', 0);
                set(app.reversPeaks, 'Value', 0);
                set(app.varBeg, 'Limits', [0 inf]);
                set(app.varBeg, 'Value', 0);
                set(app.varEnd, 'Limits', [0 inf]);
                set(app.varEnd, 'Value', 0);
                set(app.includeEndsValue, 'Limits', [0 inf]);
                app.selectedVariableName = '';
                app.curvesIncluded = 0;
                app.includeCurves.Data = {nan nan};
                app.selectedVariable = 0;
                app.background = 0;
                app.weights = 0;
                app.variableWithoutBkg = 0;
                app.plotSwitch = 1;
                plot(app.plot1,NaN);
                xlabel(app.plot1,'');
                ylabel(app.plot1,'');
                plot(app.plot2,NaN);
                xlabel(app.plot2,'');
                ylabel(app.plot2,'');
            else
                app = app;
            end
        end
    end
    
    methods (Access = public)
        
        % return results of the optimization to arPLS GUI
        function fitBackgroundAfterGAOptimization(app,bestLambda,bestRatio,bestIncludeEndsNb)
            % update Lambda value
            app.lambdaText.Value = bestLambda;
            app.lambdaValue = bestLambda;
            set(app.lambdaSlider,'Value',log10(app.lambdaValue));
            % update Ratio value
            app.ratioText.Value = bestRatio;
            app.ratioValue = bestRatio;
            set(app.ratioSlider,'Value',abs(log10(app.ratioValue)));
            % udate includeEnds value
            app.includeEndsValue.Value = bestIncludeEndsNb;
            % fit arPLS background
            fitBackgroundButtonPushed(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            % Check and set path
            fileLocationAndName = mfilename('fullpath');
            [~,name,~] = fileparts(fileLocationAndName);
            currentLocationToTest = [pwd filesep name];
            if strcmp(fileLocationAndName,currentLocationToTest)
                addpath(pwd);
                savepath;
            end
            % Setup
            set(app.exportFitToFile, 'Enable', 'off');
            set(app.exportFitToWorkspace, 'Enable', 'off');
            set(app.listOfVariables, 'Enable', 'off');
            set(app.includePotentials, 'Enable', 'off');
            set(app.reversPeaks, 'Enable', 'off');
            set(app.fitBackground, 'Enable', 'off');
            set(app.GAOptimizerButton, 'Enable', 'off');
            set(app.varBeg, 'Enable', 'off');
            set(app.varEnd, 'Enable', 'off');
            set(app.includeEndsValue, 'Enable', 'off');
            set(app.refineWeights, 'Enable', 'off');
            set(app.includePotentials,'Enable', 'off');
            set(app.plotSignalsWithoutBkg, 'Enable', 'off');
            set(app.includeCurves, 'Enable', 'off');
            set(app.toggleAllCurves, 'Enable', 'off');
            set(app.includePotentials, 'Value', 0);
            set(app.reversPeaks, 'Value', 0);
            v = ver('MATLAB');
            if str2double(v.Version) >= 9.6
                app.plot1.Toolbar.Visible = 'on';
                app.plot2.Toolbar.Visible = 'on';
            end
            app.selectedVariable = 0;
            app.background = 0;
            app.weights = 0;
            app.variableWithoutBkg = 0;
            app.plotSwitch = 1;
            app.lambdaValue = 10^(get(app.lambdaSlider,'Value'));
            app.ratioValue = 10^-(get(app.ratioSlider,'Value'));
        end

        % Menu selected function: loadFile
        function loadFileSelected(app, event)
            [file,path] = uigetfile('*.*');
            figure(app.arPLSApp);
            path_file = fullfile(path,file);
            [~,~,file_ext] = fileparts(path_file);
            switch lower(file_ext)
            case '.mat'
                S = load(path_file);
                app.loadedFileStruct = S;
                fields = fieldnames(S);
                set(app.listOfVariables, 'Enable', 'on');
                set(app.listOfVariables, 'Items', [{'Variables'} ; fields ]);
                set(app.listOfVariables, 'Value', 'Variables');
                set(app.info, 'Text', 'Select signal');
                app = clearAndDefaultApp(app);
                delete(app.GAOptimizerApp);     % close GA optimizer window if open 
            case '.txt'
                A = load(path_file);
                S = struct('x',A);
                app.loadedFileStruct = S;
                fields = fieldnames(S);
                set(app.listOfVariables, 'Enable', 'on');
                set(app.listOfVariables, 'Items', [{'Variables'} ; fields ]);
                set(app.listOfVariables, 'Value', 'Variables');
                set(app.info, 'Text', 'Select signal');
                app = clearAndDefaultApp(app);
                delete(app.GAOptimizerApp);     % close GA optimizer window if open 
            otherwise
                %not ok
                warndlg(sprintf('Unexpected file extension: %s . Accepted file formats: .mat, .txt', file_ext),'Warning');
            end
            app.background = 0;
            app.weights = 0;
            app.variableWithoutBkg = 0;
        end

        % Value changed function: listOfVariables
        function listOfVariablesValueChanged(app, event)
            value = app.listOfVariables.Value;
            app.selectedVariableName = value;
            if ~strcmp(value,'Variables')
                delete(app.GAOptimizerApp);     % close GA optimizer window if open 
                var = app.loadedFileStruct.(value);
                [m,n] = size(var);
                if m < n; var = var'; end
                app.selectedVariable = var;
                set(app.fitBackground, 'Enable', 'on');
                set(app.GAOptimizerButton, 'Enable', 'on');
                set(app.plotSignalsWithoutBkg, 'Enable', 'off');
                set(app.varBeg, 'Limits', [1 m-1]);
                set(app.varBeg, 'Value', 1);
                set(app.varEnd, 'Limits', [2 m]);
                set(app.varEnd, 'Value', m);
                set(app.exportFitToFile, 'Enable', 'on');
                set(app.exportFitToWorkspace, 'Enable', 'on');
                set(app.varBeg, 'Enable', 'on');
                set(app.varEnd, 'Enable', 'on');
                set(app.includePotentials, 'Enable', 'on');
                set(app.reversPeaks, 'Enable', 'on');
                set(app.includeCurves,'Enable', 'on');
                set(app.toggleAllCurves, 'Enable', 'on');
                set(app.includeEndsValue, 'Enable', 'on');
                set(app.includeEndsValue, 'Limits', [0 floor((m-10)/2)]);
                set(app.refineWeights, 'Enable', 'on');
                set(app.info, 'Text', 'Ready');
                if get(app.includePotentials,'Value') && n == 1
                    set(app.includePotentials,'Value',0);
                    app.curvesIncluded(1:n,:) = 1;
                elseif get(app.includePotentials,'Value') && n > 1
                    app.curvesIncluded(1:n-1,:) = 1;
                else
                    app.curvesIncluded(1:n,:) = 1;
                end
                if get(app.reversPeaks,'Value'); app = reversPeaksFunc(app); end
                app.background = 0;
                app.weights = 0;
                app.variableWithoutBkg = 0;
                app = createListOfCurves(app);
                plotData(app);
            end
        end

        % Cell edit callback: includeCurves
        function includeCurvesCellEdit(app, event)
            [~,n] = size(app.selectedVariable);
            if get(app.includePotentials,'Value'); n=n-1; end;
            includeList = app.includeCurves.Data;
            for i = 1:n
               included(i,1) = double(includeList{i,2});
            end
            app.curvesIncluded = included;
            plotData(app);
        end

        % Value changed function: includePotentials
        function includePotentialsValueChanged(app, event)
            value = app.includePotentials.Value;
            app = createListOfCurves(app);
            includePotentialsFunc(app);
        end

        % Value changed function: reversPeaks
        function reversPeaksValueChanged(app, event)
            value = app.reversPeaks.Value;
            app = reversPeaksFunc(app);
        end

        % Value changed function: varBeg
        function varBegValueChanged(app, event)
            value = app.varBeg.Value;
            valEnd = app.varEnd.Value;
            if value > valEnd
                app.varBeg.Value = 1;
            end
            app.background = 0;
            app.weights = 0;
            app.variableWithoutBkg = 0;
%             handles.includeEndsValueMax = floor( (get(handles.varEnd,'Value') - get(handles.varBeg,'Value')) / 2 );
            set(app.includeEndsValue, 'Limits', [0 floor((app.varEnd.Value - app.varBeg.Value)/2)]);
            plotData(app);
        end

        % Value changed function: varEnd
        function varEndValueChanged(app, event)
            value = app.varEnd.Value;
            valBeg = app.varBeg.Value;
            [m,~] = size(app.selectedVariable);
            if value < valBeg
                app.varEnd.Value = m;
            end
            plotData(app);
            app.background = 0;
            app.weights = 0;
            app.variableWithoutBkg = 0;
%             handles.includeEndsValueMax = floor( (get(handles.varEnd,'Value') - get(handles.varBeg,'Value')) / 2 );
            set(app.includeEndsValue, 'Limits', [0 floor((app.varEnd.Value - app.varBeg.Value)/2)]);
        end

        % Button pushed function: toggleAllCurves
        function toggleAllCurvesButtonPushed(app, event)
            text = app.toggleAllCurves.Text;
            [~,n] = size(app.selectedVariable);
            if get(app.includePotentials,'Value'); n=n-1; end;
            includeList = app.includeCurves.Data;
            if text == "Exclude all"
                for i = 1:n
                   includeList{i,2} = false;
                   included(i,1) = false;
                end
                app.toggleAllCurves.Text = "Include all";
            else
                for i = 1:n
                   includeList{i,2} = true;
                   included(i,1) = true;
                end
                app.toggleAllCurves.Text = "Exclude all";
            end
            app.curvesIncluded = included;
            app.includeCurves.Data = includeList;
            plotData(app);
        end

        % Value changed function: lambdaSlider
        function lambdaSliderValueChanged(app, event)
            value = app.lambdaSlider.Value;
            app.lambdaValue = round(10^(value));
            set(app.lambdaText,'Value',app.lambdaValue);
        end

        % Value changed function: lambdaText
        function lambdaTextValueChanged(app, event)
            value = app.lambdaText.Value;
            app.lambdaValue = value;
            set(app.lambdaSlider,'Value',log10(app.lambdaValue));
        end

        % Value changed function: ratioSlider
        function ratioSliderValueChanged(app, event)
            value = app.ratioSlider.Value;
            app.ratioValue = 10^-(value);
            set(app.ratioText,'Value',app.ratioValue);
        end

        % Value changed function: ratioText
        function ratioTextValueChanged(app, event)
            value = app.ratioText.Value;
            app.ratioValue = value;
            set(app.ratioSlider,'Value',abs(log10(value)));
        end

        % Value changed function: includeEndsValue
        function includeEndsValueValueChanged(app, event)
            value = app.includeEndsValue.Value;
            set(app.includeEndsValue,'Value',round(value));
        end

        % Value changed function: maxIterations
        function maxIterationsValueChanged(app, event)
            value = app.maxIterations.Value;
            set(app.maxIterations,'Value',round(value));
        end

        % Button pushed function: fitBackground
        function fitBackgroundButtonPushed(app, event)
            if sum(app.curvesIncluded) >= 1
                varToFitBkg = app.selectedVariable;
                beg = get(app.varBeg,'Value');
                endd = get(app.varEnd,'Value');
                curvesIncl = app.curvesIncluded;
                set(app.info, 'Text', 'Calculating');
                set(app.fitBackground, 'Enable', 'off');
                set(app.GAOptimizerButton, 'Enable', 'off');
                drawnow
                if get(app.includePotentials,'Value')
                    curvesIncl = [0; curvesIncl];
                    app = arPLS(app,varToFitBkg(beg:endd,curvesIncl>0));
                    app.variableWithoutBkg = [varToFitBkg(beg:endd,1) varToFitBkg(beg:endd,curvesIncl>0)-app.background];
                else
                    app = arPLS(app,varToFitBkg(beg:endd,curvesIncl>0));
                    app.variableWithoutBkg = varToFitBkg(beg:endd,curvesIncl>0)-app.background;
                end
                plotDataAndBkg(app);
                set(app.fitBackground, 'Enable', 'on');
                set(app.GAOptimizerButton, 'Enable', 'on');
                set(app.plotSignalsWithoutBkg, 'Enable', 'on');
                set(app.info, 'Text', 'Background calculated');
                app.plotSwitch = 1;
                set(app.plotSignalsWithoutBkg,'Text','Signals without background');
            else
                warndlg('No signal to fit background','Warning');
            end
        end

        % Button pushed function: plotSignalsWithoutBkg
        function plotSignalsWithoutBkgButtonPushed(app, event)
            if app.plotSwitch
                plotDataWithoutBkg(app);
                app.plotSwitch = 0;
                set(app.plotSignalsWithoutBkg,'Text','Signals and fitted background');
            else
                plotDataAndBkg(app);
                app.plotSwitch = 1;
                set(app.plotSignalsWithoutBkg,'Text','Signals without background');
            end
        end

        % Menu selected function: exportFitToFile
        function exportFitToFileMenuSelected(app, event)
            if length(app.background) > 1 && sum(app.curvesIncluded) >= 1
                [filename, pathname] = uiputfile('arPLS_bkg_results.mat','Save as');
                figure(app.arPLSApp);
                if length(filename) > 1 && length(pathname) > 1
                    path_file = fullfile(pathname,filename);
                    beg = get(app.varBeg,'Value');
                    endd = get(app.varEnd,'Value');
                    curvesIncl = app.curvesIncluded;
                    if get(app.includePotentials,'Value') 
                        curvesIncl = [1; curvesIncl];
                    end
                    x = app.selectedVariable(beg:endd,curvesIncl>0);
                    bkg = app.background;
                    x_bkg = app.variableWithoutBkg;
                    w = app.weights;
                    save(path_file,'x','bkg','x_bkg','w');
                    set(app.info, 'Text', sprintf('The data has been exported to %s file.',filename));
                    fprintf('The data has been exported to %s file.\n',filename);
                end
            else
                warndlg('No data to export. Fit background first.','Warning');
            end
        end

        % Menu selected function: exportFitToWorkspace
        function exportFitToWorkspaceMenuSelected(app, event)
            if length(app.background) > 1 && sum(app.curvesIncluded) >= 1
                beg = get(app.varBeg,'Value');
                endd = get(app.varEnd,'Value');
                curvesIncl = app.curvesIncluded;
                if get(app.includePotentials,'Value') 
                    curvesIncl = [1; curvesIncl]; 
                end
                x = app.selectedVariable(beg:endd,curvesIncl>0);
                bkg = app.background;
                x_bkg = app.variableWithoutBkg;
                w = app.weights;
                assignin('base','x',x);
                assignin('base','bkg',bkg);
                assignin('base','x_bkg',x_bkg);
                assignin('base','w',w);
                set(app.info, 'Text', 'The data has been exported to workspace.');
                fprintf('The data has been exported to workspace.\n');
            else
                warndlg('No data to export. Fit background first.','Warning');
            end
        end

        % Button pushed function: GAOptimizerButton
        function GAOptimizerButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            set(app.GAOptimizerButton, 'Enable', 'off');
        
            % Get data to pass to GA Optimizer app
            beg = get(app.varBeg,'Value');
            endd = get(app.varEnd,'Value');
            curvesIncl = app.curvesIncluded;
            if get(app.includePotentials,'Value') 
                curvesIncl = [1; curvesIncl];
            end
            signals = app.selectedVariable(beg:endd,curvesIncl>0);
            
            % Call GA Optimizer with input data
            app.GAOptimizerApp = GA_optimizer(app,signals);
        end

        % Close request function: arPLSApp
        function arPLSAppCloseRequest(app, event)
            % Delete both apps
            delete(app.GAOptimizerApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.arPLSApp = uifigure('Visible', 'off');
            app.arPLSApp.Name = 'arPLS GUI';
            app.arPLSApp.CloseRequestFcn = createCallbackFcn(app, @arPLSAppCloseRequest, true);
            app.fileMenu = uimenu(app.arPLSApp);
            app.fileMenu.Text = 'File';
            app.loadFile = uimenu(app.fileMenu);
            app.loadFile.MenuSelectedFcn = createCallbackFcn(app, @loadFileSelected, true);
            app.loadFile.Text = 'Load file';
            app.exportFitToFile = uimenu(app.fileMenu);
            app.exportFitToFile.MenuSelectedFcn = createCallbackFcn(app, @exportFitToFileMenuSelected, true);
            app.exportFitToFile.Text = 'Export fit to .mat file';
            app.exportFitToWorkspace = uimenu(app.fileMenu);
            app.exportFitToWorkspace.MenuSelectedFcn = createCallbackFcn(app, @exportFitToWorkspaceMenuSelected, true);
            app.exportFitToWorkspace.Text = 'Export fit to workspace';
            app.plot1 = uiaxes(app.arPLSApp);
            title(app.plot1, 'Signals and fitted background')
            xlabel(app.plot1, 'point number')
            ylabel(app.plot1, 'a.u.')
            app.plot2 = uiaxes(app.arPLSApp);
            title(app.plot2, 'Weights (W)')
            xlabel(app.plot2, 'point number')
            ylabel(app.plot2, 'w')
            app.ImportedsignalsLabel = uilabel(app.arPLSApp);
            app.ImportedsignalsLabel.Text = 'Imported signals';
            app.FitarPLSbackgroundLabel = uilabel(app.arPLSApp);
            app.FitarPLSbackgroundLabel.Text = 'Fit arPLS background';
            app.loadedsSgnalsPanel = uipanel(app.arPLSApp);
            app.loadedsSgnalsPanel.Title = 'Loaded file';
            app.SelectedvariabLabel = uilabel(app.loadedsSgnalsPanel);
            app.SelectedvariabLabel.Text = 'selected matrix';
            app.listOfVariables = uidropdown(app.loadedsSgnalsPanel);
            app.listOfVariables.Items = {'Load file'};
            app.listOfVariables.ValueChangedFcn = createCallbackFcn(app, @listOfVariablesValueChanged, true);
            app.listOfVariables.Value = 'Load file';
            app.includePotentials = uicheckbox(app.loadedsSgnalsPanel);
            app.includePotentials.ValueChangedFcn = createCallbackFcn(app, @includePotentialsValueChanged, true);
            app.includePotentials.Text = 'x values in first column';
            app.reversPeaks = uicheckbox(app.loadedsSgnalsPanel);
            app.reversPeaks.ValueChangedFcn = createCallbackFcn(app, @reversPeaksValueChanged, true);
            app.reversPeaks.Text = 'reverse curves';
            app.arPLSparametersPanel = uipanel(app.arPLSApp);
            app.arPLSparametersPanel.Title = 'arPLS parameters';
            app.LambdaPanel = uipanel(app.arPLSparametersPanel);
            app.LambdaPanel.Title = 'Lambda';
            app.SliderLabel = uilabel(app.LambdaPanel);
            app.SliderLabel.Text = '10^';
            app.lambdaSlider = uislider(app.LambdaPanel);
            app.lambdaSlider.Limits = [1 6];
            app.lambdaSlider.ValueChangedFcn = createCallbackFcn(app, @lambdaSliderValueChanged, true);
            app.lambdaSlider.Value = 3;
            app.lambdaText = uieditfield(app.LambdaPanel, 'numeric');
            app.lambdaText.Limits = [10 1000000];
            app.lambdaText.ValueDisplayFormat = '%.0f';
            app.lambdaText.ValueChangedFcn = createCallbackFcn(app, @lambdaTextValueChanged, true);
            app.lambdaText.Value = 1000;
            app.OtherPanel = uipanel(app.arPLSparametersPanel);
            app.OtherPanel.Title = 'Other';
            app.maxnumberofiterationsLabel = uilabel(app.OtherPanel);
            app.maxnumberofiterationsLabel.Text = 'max number of iterations';
            app.maxIterations = uispinner(app.OtherPanel);
            app.maxIterations.Step = 10;
            app.maxIterations.Limits = [10 1000];
            app.maxIterations.ValueDisplayFormat = '%.0f';
            app.maxIterations.ValueChangedFcn = createCallbackFcn(app, @maxIterationsValueChanged, true);
            app.maxIterations.Value = 100;
            app.SetW1forendpointsLabel = uilabel(app.OtherPanel);
            app.SetW1forendpointsLabel.Text = 'wbe (set w=1)';
            app.includeEndsValue = uispinner(app.OtherPanel);
            app.includeEndsValue.Limits = [0 Inf];
            app.includeEndsValue.ValueDisplayFormat = '%.0f';
            app.includeEndsValue.ValueChangedFcn = createCallbackFcn(app, @includeEndsValueValueChanged, true);
            app.refinewthresholdLabel = uilabel(app.OtherPanel);
            app.refinewthresholdLabel.Text = 'w threshold';
            app.refineWeights = uispinner(app.OtherPanel);
            app.refineWeights.Step = 0.01;
            app.refineWeights.Limits = [0 0.5];
            app.refineWeights.Value = 0.1;
            app.RatioPanel = uipanel(app.arPLSparametersPanel);
            app.RatioPanel.Title = 'Ratio';
            app.Label = uilabel(app.RatioPanel);
            app.Label.Text = '10^-';
            app.ratioSlider = uislider(app.RatioPanel);
            app.ratioSlider.Limits = [1 6];
            app.ratioSlider.ValueChangedFcn = createCallbackFcn(app, @ratioSliderValueChanged, true);
            app.ratioSlider.Value = 2;
            app.ratioText = uieditfield(app.RatioPanel, 'numeric');
            app.ratioText.Limits = [1e-06 0.1];
            app.ratioText.ValueChangedFcn = createCallbackFcn(app, @ratioTextValueChanged, true);
            app.ratioText.Value = 0.01;
            app.GAOptimizerButton = uibutton(app.arPLSparametersPanel, 'push');
            app.GAOptimizerButton.ButtonPushedFcn = createCallbackFcn(app, @GAOptimizerButtonPushed, true);
            app.GAOptimizerButton.Text = 'GA Optimizer';
            app.fitBackground = uibutton(app.arPLSApp, 'push');
            app.fitBackground.ButtonPushedFcn = createCallbackFcn(app, @fitBackgroundButtonPushed, true);
            app.fitBackground.Text = 'Fit background';
            app.plotSignalsWithoutBkg = uibutton(app.arPLSApp, 'push');
            app.plotSignalsWithoutBkg.ButtonPushedFcn = createCallbackFcn(app, @plotSignalsWithoutBkgButtonPushed, true);
            app.plotSignalsWithoutBkg.Text = 'Show signals without background';
            app.ConstraincurvespointsPanel = uipanel(app.arPLSApp);
            app.ConstraincurvespointsPanel.Title = 'Constrain curves points';
            app.varBeg = uispinner(app.ConstraincurvespointsPanel);
            app.varBeg.Limits = [0 Inf];
            app.varBeg.ValueDisplayFormat = '%.0f';
            app.varBeg.ValueChangedFcn = createCallbackFcn(app, @varBegValueChanged, true);
            app.varEnd = uispinner(app.ConstraincurvespointsPanel);
            app.varEnd.Limits = [0 Inf];
            app.varEnd.ValueDisplayFormat = '%.0f';
            app.varEnd.ValueChangedFcn = createCallbackFcn(app, @varEndValueChanged, true);
            app.Label_2 = uilabel(app.ConstraincurvespointsPanel);
            app.Label_2.Text = ':';
            app.ExcludecurvesPanel = uipanel(app.arPLSApp);
            app.ExcludecurvesPanel.Title = 'Exclude curves';
            app.includeCurves = uitable(app.ExcludecurvesPanel);
            app.includeCurves.ColumnName = {'curve'; 'included'};
            app.includeCurves.RowName = {};
            app.includeCurves.ColumnEditable = [false true];
            app.includeCurves.CellEditCallback = createCallbackFcn(app, @includeCurvesCellEdit, true);
            app.toggleAllCurves = uibutton(app.ExcludecurvesPanel, 'push');
            app.toggleAllCurves.ButtonPushedFcn = createCallbackFcn(app, @toggleAllCurvesButtonPushed, true);
            app.toggleAllCurves.Text = 'Exclude all';
            app.info = uilabel(app.arPLSApp);
            app.info.Text = 'Load data from file';
            % Show the figure after all components are created
            app.arPLSApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arPLS_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.arPLSApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.arPLSApp)
        end
    end
end